classdef app_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
        Button    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            data_c3d = c3dserver
            methods(data_c3d);
            invoke(data_c3d);
            methodsview(data_c3d)
            openc3d(data_c3d)
            
            count = data_c3d.GetVideoFrame(1);
            number_3dpoint = data_c3d.GetNumber3DPoints;
%             number_point = 3;
%             data_xyz = zeros(count,3);
            data_x = zeros(count,number_3dpoint);
            data_y = zeros(count,number_3dpoint);
            data_z = zeros(count,number_3dpoint);
            
            for i = 1:count
                for j = 0:number_3dpoint-1
%                data_name = zeros(50,1);
%                data_xyz(i,1) = data_c3d.GetPointData(0, 0, i, '1');
%                data_xyz(i,2) = data_c3d.GetPointData(0, 1, i, '1');
%                data_xyz(i,3) = data_c3d.GetPointData(0, 2, i, '1');
                    data_x(i,j+1) = data_c3d.GetPointData(j, 0, i, '1');
                    data_y(i,j+1) = data_c3d.GetPointData(j, 1, i, '1');
                    data_z(i,j+1) = data_c3d.GetPointData(j, 2, i, '1');
                end
            end
            
%             plot3(data_x,data_y,data_z)
%             legend('R.Off', 'R.Sh', 'R.El', 'R.Wir', 'L.Sh', 'L.El', 'L.wir')
            
            for i = 1:count
                data_xyz = [data_x(i,4), data_y(i,4), data_z(i,4); ...
                    data_x(i,3), data_y(i,3), data_z(i,3); ...
                    data_x(i,2), data_y(i,2), data_z(i,2); ...
                    data_x(i,1), data_y(i,1), data_z(i,1); ...
                    data_x(i,5), data_y(i,5), data_z(i,5); ...
                    data_x(i,6), data_y(i,6), data_z(i,6); ...
                    data_x(i,7), data_y(i,7), data_z(i,7)];
                p = plot3(app.UIAxes,data_xyz(:,1),data_xyz(:,2),data_xyz(:,3),...
                    '-o','Color','r','MarkerSize',10,'MarkerFaceColor','#D9FFFF')
                hold on;
                pause(0.05);
                delete(p)
            end
            
%             plot3(app.UIAxes,data_x,data_y,data_z)
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end